classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RunButton                 matlab.ui.control.Button
        FolderEditField           matlab.ui.control.EditField
        UsecustomdatasetLabel     matlab.ui.control.Label
        UseCustomDataSwitch       matlab.ui.control.Switch
        PresetsButtonGroup        matlab.ui.container.ButtonGroup
        SetAButton                matlab.ui.control.RadioButton
        SetBButton                matlab.ui.control.RadioButton
        SetCButton                matlab.ui.control.RadioButton
        FolderLabel               matlab.ui.control.Label
        ErrorValueEditFieldLabel  matlab.ui.control.Label
        ErrorValueEditField       matlab.ui.control.NumericEditField
        PlayAnimationButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                set(app.SetAButton, 'enable', 'on');
                set(app.SetBButton, 'enable', 'on');
                set(app.SetCButton, 'enable', 'on');
                set(app.FolderEditField, 'enable', 'off');
                set(app.FolderEditField, 'editable', 'off');
                set(app.FolderLabel, 'enable', 'off');
        end

        % Button pushed function: RunButton
        function run(app, event)
            %replace below constants with appropriate data
            
            PRESET_FILE_FOLDER = 'C:\\blah\blah\';
            SETA_NAME = 'T002';
            SETB_NAME = 'T003';
            SETC_NAME = 'T004';
            
            folder_location = 0;
            if strcmp(app.UseCustomDataSwitch.Value, 'On')
                folder_location = app.FolderEditField.Value;
            else
                if app.SetAButton.Enable
                    folder_location = PRESET_FILE_FOLDER + SETA_NAME;
                elseif app.SetBButton.Enable
                    folder_location = PRESET_FILE_FOLDER + SETB_NAME;
                elseif app.SetCButton.Enable
                    folder_location = PRESET_FILE_FOLDER + SETC_NAME;
                end
            end
            main(folder_location);
        end

        % Value changed function: UseCustomDataSwitch
        function toggle_dataset(app, event)
            value = app.UseCustomDataSwitch.Value;
            if strcmp(value, 'On')
                set(app.SetAButton, 'enable', 'off');
                set(app.SetBButton, 'enable', 'off');
                set(app.SetCButton, 'enable', 'off');
                set(app.FolderEditField, 'enable', 'on');
                set(app.FolderEditField, 'editable', 'on');
                set(app.FolderLabel, 'enable', 'on');
            elseif strcmp(value, 'Off')
                set(app.SetAButton, 'enable', 'on');
                set(app.SetBButton, 'enable', 'on');
                set(app.SetCButton, 'enable', 'on');
                set(app.FolderEditField, 'enable', 'off');
                set(app.FolderEditField, 'editable', 'off');
                set(app.FolderLabel, 'enable', 'off');
            end
        end

        % Button pushed function: PlayAnimationButton
        function play_animation(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Editable = 'off';
            app.FolderEditField.Enable = 'off';
            app.UsecustomdatasetLabel = uilabel(app.UIFigure);
            app.UsecustomdatasetLabel.Text = 'Use custom dataset';
            app.UseCustomDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseCustomDataSwitch.ValueChangedFcn = createCallbackFcn(app, @toggle_dataset, true);
            app.PresetsButtonGroup = uibuttongroup(app.UIFigure);
            app.PresetsButtonGroup.Title = 'Presets';
            app.SetAButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetAButton.Text = 'Set A';
            app.SetAButton.Value = true;
            app.SetBButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetBButton.Text = 'Set B';
            app.SetCButton = uiradiobutton(app.PresetsButtonGroup);
            app.SetCButton.Text = 'Set C';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.ErrorValueEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorValueEditFieldLabel.Text = 'Error Value';
            app.ErrorValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayAnimationButton = uibutton(app.UIFigure, 'push');
            app.PlayAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @play_animation, true);
            app.PlayAnimationButton.Text = 'Play Animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end